classdef invres2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Geolistrik_1D_Wenner_Schlumberger  matlab.ui.Figure
        rescurve                  matlab.ui.control.UIAxes
        inv                       matlab.ui.control.UIAxes
        konfigurasiDropDownLabel  matlab.ui.control.Label
        konfigurasi               matlab.ui.control.DropDown
        lapisanEditFieldLabel     matlab.ui.control.Label
        lapisan                   matlab.ui.control.NumericEditField
        data                      matlab.ui.control.EditField
        dataobsButton             matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
        Button_4                  matlab.ui.control.Button
        RMSEditFieldLabel         matlab.ui.control.Label
        RMS                       matlab.ui.control.NumericEditField
        XlimSliderLabel           matlab.ui.control.Label
        XlimSlider                matlab.ui.control.Slider
        resistivity               matlab.ui.control.Table
        ketebalan                 matlab.ui.control.Table
        EditField_2Label_2        matlab.ui.control.Label
        savedat                   matlab.ui.control.Button
        Button                    matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        figwindowButton           matlab.ui.control.Button
        er                        matlab.ui.control.Label
        editButton                matlab.ui.control.Button
        ylimEditFieldLabel        matlab.ui.control.Label
        lim                       matlab.ui.control.NumericEditField
        toEditFieldLabel          matlab.ui.control.Label
        max                       matlab.ui.control.NumericEditField
        SavefigButton             matlab.ui.control.Button
        Button_3                  matlab.ui.control.Button
        dataButton                matlab.ui.control.Button
        minButton                 matlab.ui.control.Button
        KedMaxEditFieldLabel      matlab.ui.control.Label
        KedMax                    matlab.ui.control.NumericEditField
        IterasiEditFieldLabel     matlab.ui.control.Label
        Iterasi                   matlab.ui.control.NumericEditField
        timesEditFieldLabel       matlab.ui.control.Label
        times                     matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            dat=get(app.UITable,'data');
            if isempty(dat)
                dat=zeros(1,2)
            end
            dat(end+1,:)=0;
            set(app.UITable,'data',dat);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            dat=get(app.UITable,'data');
            dat(end,:)=[];
            set(app.UITable,'data',dat);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            
         
            %set(app.UITable,'data',cell(size(get(app.UITable,'data'))))
            plot1=evalin('base','plot1')
            plot2=evalin('base','plot2')
            set(app.UITable, 'Data', {})
            set(app.resistivity, 'Data', {})
            set(app.ketebalan, 'Data', {})
            set(app.data,'Value','');
            app.RMS.Value=0
            app.lapisan.Value=0;
            app.XlimSlider.Value=0;
           app.er.Text='....';
           delete(plot1)
           delete(plot2)
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
       tic;
            addpath(genpath('Wenner'),genpath('Schlumberger'),genpath('Dipole-Dipole'));
            lap=app.lapisan.Value;
            app.times.Value=0;
            app.Iterasi.Value=0;
            a=app.data.Value;
            set(app.UITable,'ColumnEditable',logical([1 1]))
            f=get(app.UITable,'data');
            app.er.Text='.......';
            if isempty(f)
            	app.er.Text='data kosong';
                app.er.FontColor='r';
            	return;
            end
            if lap <= 1
            app.er.Text='lapisan kurang sama 1';
            app.er.FontColor='r';
            return;
            end
            if strcmp(app.konfigurasi.Value,'Schlumberger')
            [ds ro4 bvec r tebal iteration]=tschrinv(lap,f);
            elseif strcmp(app.konfigurasi.Value,'Wenner')
                [ds ro4 bvec r tebal iteration]=twennerinv(lap,f);
            else 
                [ds ro4 bvec r tebal iteration]=dipoleinv(lap,f);
            end
            app.Iterasi.Value=iteration;
            %set(app.rescurve,'YLim', [min max]);
            plot1=loglog(app.rescurve,bvec,ds,'ro',bvec,ro4,'b');
            observed=ds;
            calculated=ro4;
            legend(app.rescurve,'obs','cal');
            format bank;
            limity=get(app.rescurve,'Ylim')
            rr=[0,r];
            tt=[0,cumsum(tebal),max(tebal)*10];
            plot2=stairs(app.inv,rr,tt,'r-');
            app.inv.YDir='reverse';
            app.inv.XScale='log';
            set(app.inv, 'XLim', [0 1000], 'YLim', [0 150]);
            rms=norm(ro4-ds)/sqrt(length(ds));
            app.RMS.Value=rms;
            res=r';
            te=tebal';
            set(app.resistivity,'data',res);
            set(app.ketebalan,'data',te)
            assignin('base','plot1',plot1)
            assignin('base','plot2',plot2)
            setappdata(0,'foo',[bvec' ro4'])
            setappdata(0,'bvec',bvec)
            setappdata(0,'ds',ds)
            setappdata(0,'r',r)
            setappdata(0,'tebal',tebal)
            app.lim.Value=limity(1);
            app.max.Value=limity(2);
            app.times.Value=toc;
            
        end

        % Value changed function: KedMax
        function KedMaxValueChanged(app, event)
            kedmax = app.KedMax.Value;
            value = app.XlimSlider.Value;
            v=10^value
            set(app.inv, 'XLim', [v 1000+v] , 'Ylim',[0 kedmax]);
                        value2 = app.max.Value;
            value1 = app.lim.Value;
            set(app.rescurve, 'YLim', [value1 value2]);
        end

        % Button pushed function: SavefigButton
        function SavefigButtonPushed(app, event)
                       iterasi=app.Iterasi.Value;
           
            fig1=figure(1)            
            ux = app.rescurve;
            ax = axes;
            %ax.XScale='log';
            %ax.YScale='log';
            xlabel(ax,'AB/2 (m)');
            ylabel(ax,'Resistivitas Semu (\Omegam)');
            value1 = app.lim.Value;
            value2 = app.max.Value;
            ylim(ax,[value1 value2]);
            %hleg=legend(ax,'obs','cal')
            set(fig1,'visible','off')
            copyobj(ux.Children,ax)
            fig2=figure(2)            
            ux2 = app.inv;
            ax2 = axes;
            ax2.YDir='reverse';
            ax2.XScale='log';
            title (ax2,['\bf \fontsize{14}\fontname{Times}rms = ', num2str(app.RMS.Value),'                               iterasi =',num2str(iterasi),'                                    ']);
            ylim(ax2,[0 150]);
            value = app.XlimSlider.Value;
            v=10^value
            kmax=app.KedMax.Value;
            xlim(ax2,[1 1000+v]);
            ylim(ax2,[0 kmax]);
            xlabel(ax2,'Resistivitas (\Omegam)');
            ylabel(ax2,'Kedalaman (m)');
            set(fig2,'visible','off')
            copyobj(ux2.Children,ax2);
            figure(3)
            ax1 = zeros(1,2);
    for i = 1:2
    ax1(i)=subplot(1,2,i);
    
    if i==1
        ax.XScale='log';
            ax.YScale='log';
    end 
    set(ax1(i),'visible','off')
    end
            % Now copy contents of each figure over to destination figure
            % Modify position of each axes as it is transferred
    for i = 2:-1:1
    figure(i)
    h = get(gcf,'Children');
    newh = copyobj(h,3)
    set(i,'visible','off')
    
    for j = 1:length(newh)
            posnewh = get(newh(j),'Position');
            possub  = get(ax1(i),'Position');
            set(newh(j),'Position',...
            [possub(1) posnewh(2) possub(3) posnewh(4)])
            %        resizeLegend();
    end
            delete(ax1(i));
    end
    
            fig3=figure(3)
    legend('obs','cal')
    setappdata(0,'fig3',fig3)
            close (1)
            close (2)
            set(3,'visible','off')
         [filename, pathname]=uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files';...
          '*.*','All Files' },'Save Image');
      if filename == 0
            	% User clicked the Cancel button.
            	return;
      end
      as=fullfile(pathname,filename);
            export_fig(3,as)
            app.Geolistrik_1D_Wenner_Schlumberger.Visible = 'off';
            app.Geolistrik_1D_Wenner_Schlumberger.Visible = 'on';
            
            
        end

        % Value changed function: XlimSlider
        function XlimSliderValueChanged(app, event)
            value = app.XlimSlider.Value;
            v=10^value
            kedmax=app.KedMax.Value;
            set(app.inv, 'XLim', [1 1000+v] , 'Ylim',[0 kedmax]);
        end

        % Button pushed function: dataButton
        function dataButtonPushed(app, event)
            newdata=getappdata(0,'foo');
            f = figure;
            t = uitable(f);
            t.ColumnName = {'AB/2','Apparent Resitivity'}
            t.Data=newdata;
        end

        % Button pushed function: dataobsButton
        function dataobsButtonPushed(app, event)
            [filename, pathname] = uigetfile({'*.txt'},'File Selector');
                if filename == 0
            	% User clicked the Cancel button.
            	return;
                end
            app.Geolistrik_1D_Wenner_Schlumberger.Visible = 'off';
            app.Geolistrik_1D_Wenner_Schlumberger.Visible = 'on';
            a=strcat(pathname, filename);
            app.data.Value=a;
            f=load(a);
            set(app.UITable,'data',f)
        end

        % Button pushed function: editButton
        function editButtonPushed(app, event)
            set(app.UITable,'ColumnEditable',logical([1 1]))
        end

        % Button pushed function: figwindowButton
        function figwindowButtonPushed(app, event)
            iterasi=app.Iterasi.Value;
           
            fig1=figure(1)            
            ux = app.rescurve;
            ax = axes;
            %ax.XScale='log';
            %ax.YScale='log';
            xlabel(ax,'AB/2 (m)');
            ylabel(ax,'Resistivitas Semu (\Omegam)');
            value1 = app.lim.Value;
            value2 = app.max.Value;
            ylim(ax,[value1 value2]);
            %hleg=legend(ax,'obs','cal')
            set(fig1,'visible','off')
            copyobj(ux.Children,ax)
            fig2=figure(2)            
            ux2 = app.inv;
            ax2 = axes;
            ax2.YDir='reverse';
            ax2.XScale='log';
            title (ax2,['\bf \fontsize{14}\fontname{Times}rms = ', num2str(app.RMS.Value),'                               iterasi =',num2str(iterasi),'                                    ']);
            ylim(ax2,[0 150]);
            value = app.XlimSlider.Value;
            v=10^value
            kmax=app.KedMax.Value;
            xlim(ax2,[1 1000+v]);
            ylim(ax2,[0 kmax]);
            xlabel(ax2,'Resistivitas (\Omegam)');
            ylabel(ax2,'Kedalaman (m)');
            set(fig2,'visible','off')
            copyobj(ux2.Children,ax2);
            figure(3)
            ax1 = zeros(1,2);
    for i = 1:2
    ax1(i)=subplot(1,2,i);
    
    if i==1
        ax.XScale='log';
            ax.YScale='log';
    end 
    set(ax1(i),'visible','off')
    end
            % Now copy contents of each figure over to destination figure
            % Modify position of each axes as it is transferred
    for i = 2:-1:1
    figure(i)
    h = get(gcf,'Children');
    newh = copyobj(h,3)
    set(i,'visible','off')
    
    for j = 1:length(newh)
            posnewh = get(newh(j),'Position');
            possub  = get(ax1(i),'Position');
            set(newh(j),'Position',...
            [possub(1) posnewh(2) possub(3) posnewh(4)])
            %        resizeLegend();
    end
            delete(ax1(i));
    end
    
            fig3=figure(3)
    legend('obs','cal')
    setappdata(0,'fig3',fig3)
            close (1)
            close (2)
        end

        % Value changed function: lim
        function limValueChanged(app, event)
            value1 = app.lim.Value;
            value2 = app.max.Value;
            set(app.rescurve, 'YLim', [value1 value2]);
        end

        % Value changed function: max
        function maxValueChanged(app, event)
            value2 = app.max.Value;
            value1 = app.lim.Value;
            set(app.rescurve, 'YLim', [value1 value2]);
        end

        % Button pushed function: minButton
        function minButtonPushed(app, event)
            iter=app.Iterasi.Value;
            r1=get(app.resistivity,'data');
            t1=get(app.ketebalan,'data');
            bvec1=getappdata(0,'bvec');
            ds1=getappdata(0,'ds');
            if strcmp(app.konfigurasi.Value,'Schlumberger')
            [ro4 r tebal iteration]=fixtschrinv(r1,t1,bvec1,ds1);
            elseif strcmp(app.konfigurasi.Value,'Wenner')
                [ro4 r tebal iteration]=fixtwennerinv(r1,t1,bvec1,ds1);
            else 
                [ro4 r tebal iteration]=fixdipoleinv(r1,t1,bvec1,ds1);
            end
            loglog(app.rescurve,bvec1,ds1,'ro',bvec1,ro4,'b');
            observed=ds1;
            calculated=ro4;
            legend(app.rescurve,'obs','cal');
            format bank;
            app.Iterasi.Value=iteration+iter;
            rr=[0,r];
            tt=[0,cumsum(tebal),max(tebal)*10];
            stairs(app.inv,rr,tt,'r-');
            app.inv.YDir='reverse';
            app.inv.XScale='log';
            set(app.inv, 'XLim', [0 1000], 'YLim', [0 150]);
            rms=norm(ro4-ds1)/sqrt(length(ds1));
            app.RMS.Value=rms;
            res=r';
            te=tebal';
            set(app.resistivity,'data',res);
            set(app.ketebalan,'data',te)
            
        end

        % Cell selection callback: resistivity
        function resistivityCellSelection(app, event)
            set(app.resistivity,'ColumnEditable',logical([1]))
            set(app.ketebalan,'ColumnEditable',logical([1]))
        end

        % Button pushed function: savedat
        function savedatPushed(app, event)
          ab=get(app.UITable,'data');
          if isempty(ab)
            	app.er.Text='data kosong';
                app.er.FontColor='r';
            	return;
          end
          [filename, pathname]=uiputfile('*.txt','Menyimpan data');
          xx=get(app.UITable,'data');
          a=xx(:,1);
          b=xx(:,2);
          a(isnan(a))=[];
          b(isnan(b))=[];
          xx=[a b];
          
                if filename == 0
            	% User clicked the Cancel button.
            	return;
                end
          ass=fullfile(pathname,filename);
          dlmwrite(ass,xx,'Delimiter','\t','newline', 'pc')
          
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Geolistrik_1D_Wenner_Schlumberger = uifigure;
            app.Geolistrik_1D_Wenner_Schlumberger.Name = 'Geolistrik 1D Wenner_Schlumberger';
            app.rescurve = uiaxes(app.Geolistrik_1D_Wenner_Schlumberger);
            title(app.rescurve, 'kurva resistivitas')
            xlabel(app.rescurve, 'AB/2')
            ylabel(app.rescurve, 'Apparent Resistivity')
            app.inv = uiaxes(app.Geolistrik_1D_Wenner_Schlumberger);
            title(app.inv, 'Inversi')
            xlabel(app.inv, 'Resistivitas Lapisan')
            ylabel(app.inv, 'Kedalaman')
            app.konfigurasiDropDownLabel = uilabel(app.Geolistrik_1D_Wenner_Schlumberger);
            app.konfigurasiDropDownLabel.Text = 'konfigurasi';
            app.konfigurasi = uidropdown(app.Geolistrik_1D_Wenner_Schlumberger);
            app.konfigurasi.Items = {'Schlumberger', 'Wenner', 'Dipole-Dipole'};
            app.konfigurasi.Value = 'Wenner';
            app.lapisanEditFieldLabel = uilabel(app.Geolistrik_1D_Wenner_Schlumberger);
            app.lapisanEditFieldLabel.Text = 'lapisan';
            app.lapisan = uieditfield(app.Geolistrik_1D_Wenner_Schlumberger, 'numeric');
            app.lapisan.RoundFractionalValues = 'on';
            app.data = uieditfield(app.Geolistrik_1D_Wenner_Schlumberger, 'text');
            app.dataobsButton = uibutton(app.Geolistrik_1D_Wenner_Schlumberger, 'push');
            app.dataobsButton.ButtonPushedFcn = createCallbackFcn(app, @dataobsButtonPushed, true);
            app.dataobsButton.Text = 'data obs';
            app.UITable = uitable(app.Geolistrik_1D_Wenner_Schlumberger);
            app.UITable.ColumnName = {'AB/2'; 'App.Resistivity'};
            app.UITable.RowName = {};
            app.Button_4 = uibutton(app.Geolistrik_1D_Wenner_Schlumberger, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'run.jpg';
            app.Button_4.Text = '';
            app.RMSEditFieldLabel = uilabel(app.Geolistrik_1D_Wenner_Schlumberger);
            app.RMSEditFieldLabel.Text = 'RMS';
            app.RMS = uieditfield(app.Geolistrik_1D_Wenner_Schlumberger, 'numeric');
            app.XlimSliderLabel = uilabel(app.Geolistrik_1D_Wenner_Schlumberger);
            app.XlimSliderLabel.Text = 'Xlim';
            app.XlimSlider = uislider(app.Geolistrik_1D_Wenner_Schlumberger);
            app.XlimSlider.Limits = [0 6];
            app.XlimSlider.MajorTickLabels = {'', '', '', '', '', '', '', '', '', '', '', '', ''};
            app.XlimSlider.ValueChangedFcn = createCallbackFcn(app, @XlimSliderValueChanged, true);
            app.resistivity = uitable(app.Geolistrik_1D_Wenner_Schlumberger);
            app.resistivity.ColumnName = {'Resistivity'};
            app.resistivity.RowName = {};
            app.resistivity.CellSelectionCallback = createCallbackFcn(app, @resistivityCellSelection, true);
            app.ketebalan = uitable(app.Geolistrik_1D_Wenner_Schlumberger);
            app.ketebalan.ColumnName = {'Ketebalan'};
            app.ketebalan.RowName = {};
            app.EditField_2Label_2 = uilabel(app.Geolistrik_1D_Wenner_Schlumberger);
            app.EditField_2Label_2.Text = 'note: lapisan harus > 1';
            app.savedat = uibutton(app.Geolistrik_1D_Wenner_Schlumberger, 'push');
            app.savedat.ButtonPushedFcn = createCallbackFcn(app, @savedatPushed, true);
            app.savedat.Icon = 'save.png';
            app.savedat.Text = '';
            app.Button = uibutton(app.Geolistrik_1D_Wenner_Schlumberger, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'plus.png';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.Geolistrik_1D_Wenner_Schlumberger, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'minus.png';
            app.Button_2.Text = '';
            app.figwindowButton = uibutton(app.Geolistrik_1D_Wenner_Schlumberger, 'push');
            app.figwindowButton.ButtonPushedFcn = createCallbackFcn(app, @figwindowButtonPushed, true);
            app.figwindowButton.Text = 'fig window';
            app.er = uilabel(app.Geolistrik_1D_Wenner_Schlumberger);
            app.er.Text = '..........';
            app.editButton = uibutton(app.Geolistrik_1D_Wenner_Schlumberger, 'push');
            app.editButton.ButtonPushedFcn = createCallbackFcn(app, @editButtonPushed, true);
            app.editButton.Text = 'edit';
            app.ylimEditFieldLabel = uilabel(app.Geolistrik_1D_Wenner_Schlumberger);
            app.ylimEditFieldLabel.Text = 'ylim';
            app.lim = uieditfield(app.Geolistrik_1D_Wenner_Schlumberger, 'numeric');
            app.lim.ValueChangedFcn = createCallbackFcn(app, @limValueChanged, true);
            app.lim.Value = 1;
            app.toEditFieldLabel = uilabel(app.Geolistrik_1D_Wenner_Schlumberger);
            app.toEditFieldLabel.Text = 'to';
            app.max = uieditfield(app.Geolistrik_1D_Wenner_Schlumberger, 'numeric');
            app.max.ValueChangedFcn = createCallbackFcn(app, @maxValueChanged, true);
            app.max.Value = 1000;
            app.SavefigButton = uibutton(app.Geolistrik_1D_Wenner_Schlumberger, 'push');
            app.SavefigButton.ButtonPushedFcn = createCallbackFcn(app, @SavefigButtonPushed, true);
            app.SavefigButton.Text = 'Save fig';
            app.Button_3 = uibutton(app.Geolistrik_1D_Wenner_Schlumberger, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'cross.png';
            app.Button_3.Text = '';
            app.dataButton = uibutton(app.Geolistrik_1D_Wenner_Schlumberger, 'push');
            app.dataButton.ButtonPushedFcn = createCallbackFcn(app, @dataButtonPushed, true);
            app.dataButton.Text = 'data';
            app.minButton = uibutton(app.Geolistrik_1D_Wenner_Schlumberger, 'push');
            app.minButton.ButtonPushedFcn = createCallbackFcn(app, @minButtonPushed, true);
            app.minButton.Text = 'min';
            app.KedMaxEditFieldLabel = uilabel(app.Geolistrik_1D_Wenner_Schlumberger);
            app.KedMaxEditFieldLabel.Text = 'Ked.Max';
            app.KedMax = uieditfield(app.Geolistrik_1D_Wenner_Schlumberger, 'numeric');
            app.KedMax.ValueChangedFcn = createCallbackFcn(app, @KedMaxValueChanged, true);
            app.KedMax.Value = 150;
            app.IterasiEditFieldLabel = uilabel(app.Geolistrik_1D_Wenner_Schlumberger);
            app.IterasiEditFieldLabel.Text = 'Iterasi';
            app.Iterasi = uieditfield(app.Geolistrik_1D_Wenner_Schlumberger, 'numeric');
            app.timesEditFieldLabel = uilabel(app.Geolistrik_1D_Wenner_Schlumberger);
            app.timesEditFieldLabel.Text = 'time (s)';
            app.times = uieditfield(app.Geolistrik_1D_Wenner_Schlumberger, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = invres2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Geolistrik_1D_Wenner_Schlumberger)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Geolistrik_1D_Wenner_Schlumberger)
        end
    end
end